classdef SIRV_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VerticalGridLayout              matlab.ui.container.GridLayout
        HorizontalGridLayout            matlab.ui.container.GridLayout
        ModelViewOptionsPanel           matlab.ui.container.Panel
        StepsSpinnerLabel               matlab.ui.control.Label
        StepsSpinner                    matlab.ui.control.Spinner
        StepsizeLabel                   matlab.ui.control.Label
        StepSizeField                   matlab.ui.control.NumericEditField
        VisiblePlotsLabel               matlab.ui.control.Label
        YAxisDropDownLabel              matlab.ui.control.Label
        YAxisDropDown                   matlab.ui.control.DropDown
        XAxisDropDownLabel              matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        CompLampLabel                   matlab.ui.control.Label
        CompLamp                        matlab.ui.control.Lamp
        GUILampLabel                    matlab.ui.control.Label
        GUILamp                         matlab.ui.control.Lamp
        DataLampLabel                   matlab.ui.control.Label
        DataLamp                        matlab.ui.control.Lamp
        ShowTable                       matlab.ui.control.Table
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        StartAtDatePickerLabel          matlab.ui.control.Label
        StartAtDatePicker               matlab.ui.control.DatePicker
        Plots                           matlab.ui.container.TabGroup
        TimePlotTab                     matlab.ui.container.Tab
        TimeAxes                        matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        LoadfromthedatasourcesPanel     matlab.ui.container.Panel
        GroupSizesSpinnerLabel          matlab.ui.control.Label
        GroupSizesSpinner               matlab.ui.control.Spinner
        CutoffAgeSpinnerLabel           matlab.ui.control.Label
        CutoffAgeSpinner                matlab.ui.control.Spinner
        LoadDataAndRunModelButton       matlab.ui.control.Button
        FilenameContactMatrixEditFieldLabel  matlab.ui.control.Label
        FilenameContactMatrixEditField  matlab.ui.control.EditField
        GroupsandInitialValuesTab       matlab.ui.container.Tab
        GroupTable                      matlab.ui.control.Table
        TransmissionRatesTab            matlab.ui.container.Tab
        BetaTable                       matlab.ui.control.Table
    end

    
    properties (Access = public)
        DefaultAlpha = 0.1
        DefaultBeta = 0.4
        DefaultS = 1000
        S
        I
        R
        V
        Style
        InitialState lib.classes.ModelState
    end
    
    %% Computed convenience properties.
    properties (Dependent)
        m               double
        n               double
        dt              double
        N               double
        T               double
        X               double
        Beta            double
        Alpha           double
        VisibleDataRows logical
        GroupCategories categorical
        GroupNames      string
    end
    
    methods
        
        %Parameters
        function result = get.Beta(app)
            if(~isempty(app.InitialState))
                result = app.InitialState.Beta;
            else
                result = [];
            end
        end
        
        function result = get.Alpha(app)
            if(~isempty(app.InitialState))
                result = app.InitialState.Alpha;
            else
                result = [];
            end
        end
        
        function result = get.T(app)
            result = (0:app.n - 1) * app.dt;
        end
        
        function result = get.X(app)
            switch app.XAxisDropDown.Value
                case 'Hours'
                    result = (0:app.n-1)*app.dt*24;
                case 'Days'
                    result = (0:app.n-1)*app.dt;
                case 'Weeks'
                    result = (0:app.n-1)*app.dt/7;
                case 'Years'
                    result = (0:app.n-1)*app.dt/365.25;
                case 'Date'
                    result = app.StartAtDatePicker.Value + days((0:app.n-1)*app.dt);
                otherwise
                    result = 1:app.n;
            end
        end
        
        function result = get.N(app)
            if(~isempty(app.InitialState))
                result = app.InitialState.N;
            else
                result = [];
            end
        end
        
        function result = get.dt(app)
            result = app.StepSizeField.Value;
        end
        
        function result = get.n(app)
            result = app.StepsSpinner.Value;
        end
        
        function out = get.m(app)
            if(~isempty(app.InitialState))
                out = app.InitialState.m;
            else
                out = 0;
            end
        end
        
        function out = get.GroupCategories(app)
            if(~isempty(app.InitialState))
                out = app.InitialState.categories();
            else
                out = [];
            end
        end
        
        function out = get.GroupNames(app)
            if(~isempty(app.InitialState))
                out = string(app.GroupCategories);
            else
                out = [];
            end
        end
        
        function out = get.VisibleDataRows(app)
            out = reshape(cell2mat(app.ShowTable.Data), [],1);
        end
        
        % Max values
        function result = Ymax(app)
            values = [app.S;app.I;app.R;app.V];
            visibleValues = values(app.VisibleDataRows);
            if isempty(visibleValues)
                result = max(values,[],'all');
            else
                result = max(visibleValues,[],'all');
            end
        end
        
    end
    
    %% Methods and properties to determine the apperance of the model.
    properties(Constant)
        BusyStateColor = [1   , 0.5 , 0   ];
        IdleStateColor = [0.94, 0.94, 0.94];
    end
    
    methods(Access = protected)
        % Getting the colors
        function result = Scolors(app, index)
            colors = lib.utils.pickColors(app.Style.ScolorMap, app.m);
            result = colors(index,:);
        end
        
        function result = Icolors(app, index)
            colors = lib.utils.pickColors(app.Style.IcolorMap, app.m);
            result = colors(index,:);
        end
        
        function result = Rcolors(app, index)
            colors = lib.utils.pickColors(app.Style.RcolorMap, app.m);
            result = colors(index,:);
        end
        
        function result = Vcolors(app, index)
            colors = lib.utils.pickColors(app.Style.VcolorMap, app.m);
            result = colors(index,:);
        end
        
        function result = colors(app, index)
            colors = [
                app.Scolors(1:app.m);
                app.Icolors(1:app.m);
                app.Rcolors(1:app.m);
                app.Vcolors(1:app.m)
            ];
            result = colors(index,:);
        end
    end
    
    %% Methods and properties keeping track of the state of the API.
    properties(Dependent, Access = private)
        IsRunning     logical
        IsLoadingData logical
        IsUpdatingGUI logical
    end
    
    methods 
        function out = get.IsRunning(app)
            out = is(app.CompLamp.Color, app.BusyStateColor);
        end
        
        function set.IsRunning(app, value)
            if value
                app.CompLamp.Color = app.BusyStateColor;
            else
                app.CompLamp.Color = app.IdleStateColor;
            end
            drawnow;
        end
        
        function out = get.IsLoadingData(app)
            out = is(app.DataLamp.Color, app.BusyStateColor);
        end
        
        function set.IsLoadingData(app, value)
            if value
                app.DataLamp.Color = app.BusyStateColor;
            else
                app.DataLamp.Color = app.IdleStateColor;
            end
            drawnow;
        end
        
        function out = get.IsUpdatingGUI(app)
            out = is(app.GUILamp.Color, app.BusyStateColor);
        end
        
        function set.IsUpdatingGUI(app, value)
            if value
                app.GUILamp.Color = app.BusyStateColor;
            else
                app.GUILamp.Color = app.IdleStateColor;
            end
            drawnow;
        end
    end
    
    %% Methods to update the GUI.
    methods (Access = private)
        
        function resetInitialState(app)
            app.IsLoadingData = true;
            groups = lib.utils.createGroupBoundaries( ...
                app.GroupSizesSpinner.Value, ...
                app.CutoffAgeSpinner.Value ...
            );
            at_date = app.StartAtDatePicker.Value;
            contact_matrix = app.FilenameContactMatrixEditField.Value;
            app.InitialState = lib.classes.ModelState.standardData( ...
                at_date, ...
                groups, ...
                contact_matrix ...
            );
            app.IsLoadingData = false;
            app.loadInitialStateIntoTables();
            app.runFromInitialState();
        end
        
        function loadInitialStateIntoTables(app)
            % Loads the data from the current initial state into the
            % tables.
            if(~isempty(app.InitialState))
                app.IsUpdatingGUI = true;
                % Setting the group table.
                GT = app.InitialState.buildGoupTable();
                app.GroupTable.ColumnName = [GT.Properties.VariableNames];
                app.GroupTable.Data = table2array(GT);
                app.GroupTable.RowName = app.GroupNames;
                
                % Setting the show table.
                app.ShowTable.RowName = app.GroupNames;
                app.ShowTable.Data = num2cell(ones(app.m,4,"logical"));
                for i = 1:app.m
                    addStyle(...
                        app.ShowTable, ...
                        uistyle('BackgroundColor', app.Scolors(i)), ...
                        'cell',[i 1] ...
                    );
                    addStyle(...
                        app.ShowTable, ...
                        uistyle('BackgroundColor', app.Icolors(i)), ...
                        'cell',[i 2] ...
                    );
                    addStyle(...
                        app.ShowTable, ...
                        uistyle('BackgroundColor', app.Rcolors(i)), ...
                        'cell',[i 3] ...
                    );
                    addStyle(...
                        app.ShowTable, ...
                        uistyle('BackgroundColor', app.Vcolors(i)), ...
                        'cell',[i 4] ...
                    );
                end
                
                % Setting the beta table.
                app.BetaTable.ColumnName = app.GroupNames;
                app.BetaTable.RowName    = app.GroupNames;
                app.BetaTable.Data = app.InitialState.Beta;
                NormBeta = app.InitialState.Beta;
                NormBeta = NormBeta - min(NormBeta, [], 'all');
                NormBeta = NormBeta / max(NormBeta, [], 'all');
                NormBeta = round(NormBeta * 255 + 1);
                colors = cool;
                for i = 1:height(NormBeta)
                    for j = 1:width(NormBeta)
                        v = NormBeta(i,j);
                        s = uistyle('BackgroundColor', colors(v,:));
                        addStyle(app.BetaTable,s,'cell',[i j]);
                    end
                end
                
                app.IsUpdatingGUI = false;
            end
        end
        
        function runFromInitialState(app)
            % RUNFROMINITIALSTATE Runs the whole model, starting from
            %                     the initial state.
            if(~isempty(app.InitialState) && app.dt > 0)
                app.IsRunning = true;
                switch app.MethodDropDown.Value
                    case 'EulerForward'
                        [app.S, app.I, app.R, app.V] = app.InitialState.run_EulerForward( ...
                            app.dt, ...
                            app.n ...
                        );
                        app.IsRunning = false;
                    case 'EulerBackward'
                        [app.S, app.I, app.R, app.V] = app.InitialState.run_EulerBackward( ...
                            app.dt, ...
                            app.n ...
                        );
                        app.IsRunning = false;
                end
                
                
                app.updateAxis();
            end
        end

        function updateXAxis(app)
            switch app.XAxisDropDown.Value
                case 'Hours'
                    app.TimeAxes.XLabel.String = 'time in hours';
                case 'Days'
                    app.TimeAxes.XLabel.String = 'time in days';
                case 'Weeks'
                    app.TimeAxes.XLabel.String = 'time in weeks';
                case 'Years'
                    app.TimeAxes.XLabel.String = 'time in years';
                case 'Date'
                    app.TimeAxes.XLabel.String = '';
                otherwise
                    app.TimeAxes.XLabel.String = 'step';
            end
        end
        
        function updateAxis(app)
            
            app.IsUpdatingGUI = true;
            % Clear the current data in the axis.
            cla(app.TimeAxes)
            
            % Set the time axis.
            if(app.n > 0)
                app.updateXAxis();
            end
            
            % Getting the data that should be shown.
            v = app.VisibleDataRows;
            
            % Resume when there is data to be shown.
            if(app.m > 0 && any(v))
                
                % Setting the colors.
                colororder(app.TimeAxes, app.colors(v))
                
                % Getting all the values of the model.
                data = [app.S;app.I;app.R;app.V];
                Y = data(v,:);
                
                % Plotting the right
                yType = app.YAxisDropDown.Value;
                switch yType
                    case 1 % Show a normal plot of the data.
                        app.TimeAxes.YLabel.String = 'number of people';
                        ytickformat(app.TimeAxes, 'auto');
                        % app.TimeAxes.YLim = [0, app.Ymax];
                        plot(app.TimeAxes, app.X, Y);
                    case 2 % Plot the percentage as shown from the group.
                        app.TimeAxes.YLabel.String = 'percentage of group';
                        ytickformat(app.TimeAxes, 'percentage');
                        % app.TimeAxes.YLim = [0, 100];
                        NForEach = repmat(app.N, 3);
                        plot(app.TimeAxes, app.X, Y./NForEach(v) * 100);
                    case 3 % An area plot with amounts.
                        app.TimeAxes.YLabel.String = 'number of people';
                        ytickformat(app.TimeAxes, 'auto');
                        % app.TimeAxes.YLim = [0, max(sum(Y))];
                        area(app.TimeAxes, app.X, Y');
                    case 4 % An area plot that shows the percentage of the shown data.
                        app.TimeAxes.YLabel.String = 'percentage of shown data';
                        ytickformat(app.TimeAxes, 'percentage');
                        % app.TimeAxes.YLim = [0, 100];
                        PY = Y ./ sum(Y);
                        area(app.TimeAxes, app.X, PY' * 100);
                end
            end
            app.IsUpdatingGUI = false;
        end
        
        function updateDataTable(app)
            v = app.visibleDataRows;
            
            names = [
                strcat('S ', app.groupNames);
                strcat('I ', app.groupNames);
                strcat('R ', app.groupNames);
            ];
            app.DataTable.ColumnName = num2str(app.T');
            app.DataTable.RowName = names(v);
            data = [app.S;app.I;app.R];
            app.DataTable.Data = data(v,:);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartAtDatePicker.Value = datetime('today') - calmonths(1);
            app.ShowTable.SelectionHighlight = 'off';
            if isempty(app.Style)
                load("data/default-colors.mat", "Icolors", "Rcolors", 'Scolors',"Vcolors");
                app.Style.IcolorMap = Icolors;
                app.Style.RcolorMap = Rcolors;
                app.Style.ScolorMap = Scolors;
                app.Style.VcolorMap = Vcolors;
            end
            app.resetInitialState();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if key == 'r'
                %app.RemovedCheckBox.Value = not(app.RemovedCheckBox.Value);
                app.updateAxis();
            elseif key == 'i'
                %app.InfectiousCheckBox.Value = not(app.InfectiousCheckBox.Value);
                app.updateAxis();
            elseif key == 's'
                %app.SucceptableCheckBox.Value = not(app.SucceptableCheckBox.Value);
                app.updateAxis();
            elseif key == 'v'
                %app.VaccinatedCheckBox.Value = not(app.VaccinatedCheckBox.Value);
                app.updateAxis();
            end
        end

        % Value changed function: StepsSpinner
        function StepsSpinnerValueChanged(app, event)
            app.runFromInitialState();
        end

        % Value changed function: StepSizeField
        function StepSizeFieldValueChanged(app, event)
            app.runFromInitialState();
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            app.updateAxis();
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            app.updateAxis();
        end

        % Button pushed function: LoadDataAndRunModelButton
        function LoadDataAndRunModelButtonPushed(app, event)
            app.resetInitialState();
        end

        % Cell selection callback: ShowTable
        function ShowTableCellSelection(app, event)
            app.ShowTable.Data = num2cell(zeros(app.m, 4, 'logical'));
            app.ShowTable.Data(event.Indices(:,1),event.Indices(:,2)) = {true};
            app.updateAxis();
        end

        % Cell edit callback: ShowTable
        function ShowTableCellEdit(app, event)
            app.updateAxis();
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            app.runFromInitialState();
        end

        % Value changed function: StartAtDatePicker
        function StartAtDatePickerValueChanged(app, event)
            app.resetInitialState();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.VerticalGridLayout = uigridlayout(app.UIFigure);
            app.HorizontalGridLayout = uigridlayout(app.VerticalGridLayout);
            app.ModelViewOptionsPanel = uipanel(app.HorizontalGridLayout);
            app.ModelViewOptionsPanel.Title = 'Model/View Options';
            app.StepsSpinnerLabel = uilabel(app.ModelViewOptionsPanel);
            app.StepsSpinnerLabel.Text = 'Steps:';
            app.StepsSpinner = uispinner(app.ModelViewOptionsPanel);
            app.StepsSpinner.Step = 10;
            app.StepsSpinner.Limits = [10 Inf];
            app.StepsSpinner.ValueChangedFcn = createCallbackFcn(app, @StepsSpinnerValueChanged, true);
            app.StepsSpinner.Value = 400;
            app.StepsizeLabel = uilabel(app.ModelViewOptionsPanel);
            app.StepsizeLabel.Text = 'Step size:';
            app.StepSizeField = uieditfield(app.ModelViewOptionsPanel, 'numeric');
            app.StepSizeField.Limits = [1e-07 Inf];
            app.StepSizeField.ValueChangedFcn = createCallbackFcn(app, @StepSizeFieldValueChanged, true);
            app.StepSizeField.Value = 1;
            app.VisiblePlotsLabel = uilabel(app.ModelViewOptionsPanel);
            app.VisiblePlotsLabel.Text = 'Visible Plots:';
            app.YAxisDropDownLabel = uilabel(app.ModelViewOptionsPanel);
            app.YAxisDropDownLabel.Text = 'Y-Axis:';
            app.YAxisDropDown = uidropdown(app.ModelViewOptionsPanel);
            app.YAxisDropDown.Items = {'Number of people', 'Percentages', 'Total Stack', 'Ratio Stack'};
            app.YAxisDropDown.ItemsData = [1 2 3 4];
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 1;
            app.XAxisDropDownLabel = uilabel(app.ModelViewOptionsPanel);
            app.XAxisDropDownLabel.Text = 'X-Axis:';
            app.XAxisDropDown = uidropdown(app.ModelViewOptionsPanel);
            app.XAxisDropDown.Items = {'Step Number', 'Time in hours', 'Time in days', 'Time in weeks', 'Time in years', 'Dates'};
            app.XAxisDropDown.ItemsData = {'Step', 'Hours', 'Days', 'Weeks', 'Years', 'Date'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 'Step';
            app.CompLampLabel = uilabel(app.ModelViewOptionsPanel);
            app.CompLampLabel.Text = 'Comp:';
            app.CompLamp = uilamp(app.ModelViewOptionsPanel);
            app.GUILampLabel = uilabel(app.ModelViewOptionsPanel);
            app.GUILampLabel.Text = 'GUI:';
            app.GUILamp = uilamp(app.ModelViewOptionsPanel);
            app.DataLampLabel = uilabel(app.ModelViewOptionsPanel);
            app.DataLampLabel.Text = 'Data:';
            app.DataLamp = uilamp(app.ModelViewOptionsPanel);
            app.ShowTable = uitable(app.ModelViewOptionsPanel);
            app.ShowTable.ColumnName = {'S'; 'I'; 'R'; 'V'};
            app.ShowTable.RowName = {};
            app.ShowTable.ColumnEditable = true;
            app.ShowTable.CellEditCallback = createCallbackFcn(app, @ShowTableCellEdit, true);
            app.ShowTable.CellSelectionCallback = createCallbackFcn(app, @ShowTableCellSelection, true);
            app.MethodDropDownLabel = uilabel(app.ModelViewOptionsPanel);
            app.MethodDropDownLabel.Text = 'Method:';
            app.MethodDropDown = uidropdown(app.ModelViewOptionsPanel);
            app.MethodDropDown.Items = {'Euler Forward', 'Euler Backward'};
            app.MethodDropDown.ItemsData = {'EulerForward', 'EulerBackward'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'EulerForward';
            app.StartAtDatePickerLabel = uilabel(app.ModelViewOptionsPanel);
            app.StartAtDatePickerLabel.Text = 'Start At:';
            app.StartAtDatePicker = uidatepicker(app.ModelViewOptionsPanel);
            app.StartAtDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartAtDatePickerValueChanged, true);
            app.Plots = uitabgroup(app.HorizontalGridLayout);
            app.TimePlotTab = uitab(app.Plots);
            app.TimePlotTab.Title = 'Time Plot';
            app.TimeAxes = uiaxes(app.TimePlotTab);
            xlabel(app.TimeAxes, 'time in days')
            ylabel(app.TimeAxes, 'number of people')
            zlabel(app.TimeAxes, 'Z')
            app.TabGroup = uitabgroup(app.VerticalGridLayout);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.LoadfromthedatasourcesPanel = uipanel(app.LoadDataTab);
            app.LoadfromthedatasourcesPanel.Title = 'Load from the data sources.';
            app.GroupSizesSpinnerLabel = uilabel(app.LoadfromthedatasourcesPanel);
            app.GroupSizesSpinnerLabel.Text = 'Group Sizes';
            app.GroupSizesSpinner = uispinner(app.LoadfromthedatasourcesPanel);
            app.GroupSizesSpinner.Limits = [1 Inf];
            app.GroupSizesSpinner.Value = 10;
            app.CutoffAgeSpinnerLabel = uilabel(app.LoadfromthedatasourcesPanel);
            app.CutoffAgeSpinnerLabel.Text = 'Cutoff Age';
            app.CutoffAgeSpinner = uispinner(app.LoadfromthedatasourcesPanel);
            app.CutoffAgeSpinner.Limits = [0 100];
            app.CutoffAgeSpinner.Value = 70;
            app.LoadDataAndRunModelButton = uibutton(app.LoadfromthedatasourcesPanel, 'push');
            app.LoadDataAndRunModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataAndRunModelButtonPushed, true);
            app.LoadDataAndRunModelButton.Text = 'Load Data And Run Model';
            app.FilenameContactMatrixEditFieldLabel = uilabel(app.LoadfromthedatasourcesPanel);
            app.FilenameContactMatrixEditFieldLabel.Text = 'Filename Contact Matrix';
            app.FilenameContactMatrixEditField = uieditfield(app.LoadfromthedatasourcesPanel, 'text');
            app.FilenameContactMatrixEditField.Value = 'Contact_matrix.csv';
            app.GroupsandInitialValuesTab = uitab(app.TabGroup);
            app.GroupsandInitialValuesTab.Title = 'Groups and Initial Values';
            app.GroupTable = uitable(app.GroupsandInitialValuesTab);
            app.GroupTable.ColumnName = {'Age Group'; 'S(0)'; 'I(0)'; 'R(0)'; 'Alpha'; 'Tau'};
            app.GroupTable.RowName = {};
            app.TransmissionRatesTab = uitab(app.TabGroup);
            app.TransmissionRatesTab.Title = 'Transmission Rates (beta)';
            app.BetaTable = uitable(app.TransmissionRatesTab);
            app.BetaTable.ColumnName = '';
            app.BetaTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIRV_model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end