classdef SIR_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        VerticalGridLayout         matlab.ui.container.GridLayout
        HorizontalGridLayout       matlab.ui.container.GridLayout
        ModelOptionsPanel          matlab.ui.container.Panel
        StepsSpinnerLabel          matlab.ui.control.Label
        StepsSpinner               matlab.ui.control.Spinner
        StepsizeLabel              matlab.ui.control.Label
        StepSizeField              matlab.ui.control.NumericEditField
        SucceptableCheckBox        matlab.ui.control.CheckBox
        ShowLabel                  matlab.ui.control.Label
        InfectiousCheckBox         matlab.ui.control.CheckBox
        RemovedCheckBox            matlab.ui.control.CheckBox
        YAxisDropDownLabel         matlab.ui.control.Label
        YAxisDropDown              matlab.ui.control.DropDown
        XAxisDropDownLabel         matlab.ui.control.Label
        XAxisDropDown              matlab.ui.control.DropDown
        Plots                      matlab.ui.container.TabGroup
        TimePlotTab                matlab.ui.container.Tab
        TimeAxes                   matlab.ui.control.UIAxes
        TimeTable                  matlab.ui.container.Tab
        DataTable                  matlab.ui.control.Table
        TransmissionRatesTab_2     matlab.ui.container.Tab
        TransmissionHeatMapAxes    matlab.ui.control.UIAxes
        TabGroup                   matlab.ui.container.TabGroup
        GroupsandInitialValuesTab  matlab.ui.container.Tab
        GroupTable                 matlab.ui.control.Table
        TransmissionRatesTab       matlab.ui.container.Tab
        BetaTable                  matlab.ui.control.Table
        GroupTableContextMeny      matlab.ui.container.ContextMenu
        AddGroupMenu               matlab.ui.container.Menu
        RemoveGroupsMenu           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        DefaultAlpha = 0.1
        DefaultBeta = 0.4
        DefaultS = 1000
        S
        I
        R
        Style
    end
    
    properties (Access = private)
        GroupTableSelection % Description
    end
    
    methods (Access = private)
        
        function addGroup(app)
            % Getting info of the old groups.
            oldGroups = app.GroupTable.Data;
            m = height(oldGroups);
            
            % Get the new group
            newGroup = {true, ['Group ' num2str(m+1)], app.DefaultS, 0, 0, app.DefaultAlpha};
            
            % Update the data.
            app.GroupTable.Data = [
                oldGroups;
                newGroup
            ];
            app.GroupTable.RowName = num2str((1:m+1)');
            
            % Update the beta table.
            if(app.BetaTable.Data == 0)
                app.BetaTable.Data = eye(m+1) * app.DefaultBeta;
            else
                app.BetaTable.Data = [
                    app.BetaTable.Data, zeros(m,1);
                    zeros(1,m),app.DefaultBeta
                ];
            end
            app.updateGroups();
        end
        
        function result = groupNames(app)
            result = app.GroupTable.Data(:,2);
        end
        
        function updateGroups(app)
            names = app.groupNames();
            app.BetaTable.ColumnName = names;
            app.BetaTable.RowName = names;
            app.updateHeatMap();
            app.updateModel();
        end
        
        
        %% Getting the model data.
        function updateModel(app)
            if(app.m > 0 && app.dt > 0)
                [app.S, app.I, app.R] = lib.models.SIR_EulerForward( ...
                    app.Szero, ...
                    app.Izero, ...
                    app.Rzero, ...
                    app.beta, ...
                    app.alpha, ...
                    app.dt, ...
                    app.n ...
                );
                app.updateAxis();
            end
        end
        
        function resizeModel(app)
            if(app.m > 0 && app.dt > 0)
                oldn = width(app.S);
                if(app.n > oldn)
                    stepsNeeded = app.n - width(app.S);
                    [extraS, extraI, extraR] = lib.models.SIR_EulerForward( ...
                        app.S(:,oldn), ...
                        app.I(:,oldn), ...
                        app.R(:,oldn), ...
                        app.beta, ...
                        app.alpha, ...
                        app.dt, ...
                        stepsNeeded + 1 ...
                    );
                    
                    i = 2:(stepsNeeded + 1);
                    app.S = [app.S extraS(:,i)];
                    app.I = [app.I extraI(:,i)];
                    app.R = [app.R extraR(:,i)];
                elseif(app.n < oldn)
                    i = 1:app.n;
                    app.S = app.S(:,i);
                    app.I = app.I(:,i);
                    app.R = app.R(:,i);
                end
                
                app.updateAxis();
            end
        end
        
        function updateHeatMap(app)
            if(app.m > 0)
                groupNames = app.groupNames;
                hm = heatmap(app.TransmissionRatesTab_2, groupNames, groupNames, app.beta);
                hm.XLabel = 'Carrier';
                hm.YLabel = 'Receiver';
            else
                cla(app.TransmissionHeatMapAxes)
            end
        end
        
        % Getting that should be visible.
        function result = visibleGroups(app)
            if isempty(app.GroupTable.Data)
                result = [];
            else
                result = cell2mat(app.GroupTable.Data(:,1));
            end
        end
        
        function result = visibleDataRows(app)
            v = app.visibleGroups;
            result = [
                and(app.SucceptableCheckBox.Value, v)
                and(app.InfectiousCheckBox.Value, v)
                and(app.RemovedCheckBox.Value, v)
            ];
        end
        
        function result = X(app)
            switch app.XAxisDropDown.Value
                case 2
                    result = (0:app.n-1)*app.dt*24;
                case 3
                    result = (0:app.n-1)*app.dt;
                case 4
                    result = (0:app.n-1)*app.dt/7;
                otherwise
                    result = 1:app.n;
            end
        end
        
        function updateXAxis(app)
            switch app.XAxisDropDown.Value
                case 2
                    app.TimeAxes.XLabel.String = 'time in hours';
                    app.TimeAxes.XLim = [0,app.n * app.dt * 24];
                case 3
                    app.TimeAxes.XLabel.String = 'time in days';
                    app.TimeAxes.XLim = [0,app.n * app.dt];
                case 4
                    app.TimeAxes.XLabel.String = 'time in weeks';
                    app.TimeAxes.XLim = [0,app.n * app.dt/7];
                otherwise
                    app.TimeAxes.XLabel.String = 'step';
                    app.TimeAxes.XLim = [1,app.n+1];
            end
        end
        
        function updateAxis(app)
            % Clear the current data in the axis.
            cla(app.TimeAxes)
            
            % Set the time axis.
            if(app.n > 0)
                app.updateXAxis();
            end
            
            % Getting the data that should be shown.
            v = app.visibleDataRows;
            
            % Resume when there is data to be shown.
            disp('app.m');
            disp(app.m);
            if(app.m > 0 && any(v))
                
                % Setting the colors.
                colororder(app.TimeAxes, app.colors(v))
                
                % Getting all the values of the model.
                data = [app.S;app.I;app.R];
                Y = data(v,:);
                
                % Plotting the right
                yType = app.YAxisDropDown.Value;
                switch yType
                    case 1 % Show a normal plot of the data.
                        app.TimeAxes.YLabel.String = 'number of people';
                        ytickformat(app.TimeAxes, 'auto');
                        app.TimeAxes.YLim = [0, app.Ymax];
                        plot(app.TimeAxes, app.X, Y);
                    case 2 % Plot the percentage as shown from the group.
                        app.TimeAxes.YLabel.String = 'percentage of group';
                        ytickformat(app.TimeAxes, 'percentage');
                        app.TimeAxes.YLim = [0, 100];
                        N = repmat(app.N, 3);
                        plot(app.TimeAxes, app.X, Y./N(v) * 100);
                    case 3 % An area plot with amounts.
                        app.TimeAxes.YLabel.String = 'number of people';
                        ytickformat(app.TimeAxes, 'auto');
                        app.TimeAxes.YLim = [0, max(sum(Y))];
                        area(app.TimeAxes, app.X, Y');
                    case 4 % An area plot that shows the percentage of the shown data.
                        app.TimeAxes.YLabel.String = 'percentage of shown data';
                        ytickformat(app.TimeAxes, 'percentage');
                        app.TimeAxes.YLim = [0, 100];
                        PY = Y ./ sum(Y);
                        area(app.TimeAxes, app.X, PY' * 100);
                end
            end
        end
        
        function updateDataTable(app)
            v = app.visibleDataRows;
            
            names = [
                strcat('S ', app.groupNames);
                strcat('I ', app.groupNames);
                strcat('R ', app.groupNames);
            ];
            app.DataTable.ColumnName = num2str(app.T');
            app.DataTable.RowName = names(v);
            data = [app.S;app.I;app.R];
            app.DataTable.Data = data(v,:);
        end
        
        function result = selectedGroups(app)
            result = unique(app.GroupTableSelection(:,1)');
        end
        
        function removeSelectedGroups(app)
            rows = app.selectedGroups;
            keep = not(ismember(1:app.m, rows));
            app.GroupTable.Data = app.GroupTable.Data(keep,:);
            app.BetaTable.Data  = app.BetaTable.Data(keep,keep) ;
            app.GroupTableSelection = [];
            app.RemoveGroupsMenu.Text = 'Remove Group(s)';
            app.RemoveGroupsMenu.Enable = false;
            if length(rows) == app.m
                app.RemoveGroupsMenu.Enable = false;
            end
            app.updateGroups();
        end
        
        % Getting the colors
        function result = Scolors(app, index)
            colors = lib.utils.pickColors(app.Style.ScolorMap, app.m);
            result = colors(index,:);
        end
        
        function result = Icolors(app, index)
            colors = lib.utils.pickColors(app.Style.IcolorMap, app.m);
            result = colors(index,:);
        end
        
        function result = Rcolors(app, index)
            colors = lib.utils.pickColors(app.Style.RcolorMap, app.m);
            result = colors(index,:);
        end
        
        function result = colors(app, index)
            colors = [
                app.Scolors(1:app.m);
                app.Icolors(1:app.m);
                app.Rcolors(1:app.m)
            ];
            result = colors(index,:);
        end
    end
    
    methods (Access = public)
        
        % Initial values
        function result = Szero(app)
            result = cell2mat(app.GroupTable.Data(:,3));
        end
        
        function result = Izero(app)
            result = cell2mat(app.GroupTable.Data(:,4));
        end
        
        function result = Rzero(app)
            result = cell2mat(app.GroupTable.Data(:,5));
        end
        
        %Parameters
        function result = beta(app)
            if iscell(app.BetaTable.Data)
                result = cell2mat(app.BetaTable.Data);
            else
                result = app.BetaTable.Data;
            end
            
        end
        
        function result = alpha(app)
            result = cell2mat(app.GroupTable.Data(:,6));
        end
        
        function result = T(app)
            result = (0:app.n - 1) * app.dt;
        end
        
        function result = N(app)
            result = app.Szero + app.Izero + app.Rzero;
        end
        
        function result = dt(app)
            result = app.StepSizeField.Value;
        end
        
        function result = n(app)
            result = app.StepsSpinner.Value;
        end
        
        function result = m(app)
            result = height(app.GroupTable.Data);
        end
        
        % Max values
        function result = Smax(app)
            result = max(app.S,[],'all');
        end
        
        function result = Imax(app)
            result = max(app.I,[],'all');
        end
        
        function result = Rmax(app)
            result = max(app.R,[],'all');
        end
        
        function result = Ymax(app)
            maxValues = [app.Smax, app.Imax, app.Rmax];
            visibleValues = maxValues([app.SucceptableCheckBox.Value,...
               app.InfectiousCheckBox.Value,...
               app.RemovedCheckBox.Value]);
            if isempty(visibleValues)
                result = max(maxValues);
            else
                result = max(visibleValues);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isempty(app.Style)
                load("data/default-colors.mat", "Icolors", "Rcolors", 'Scolors');
                app.Style.IcolorMap = Icolors;
                app.Style.RcolorMap = Rcolors;
                app.Style.ScolorMap = Scolors;
            end
            app.updateModel()
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if key == 'r'
                app.RemovedCheckBox.Value = not(app.RemovedCheckBox.Value);
                app.updateAxis();
            elseif key == 'i'
                app.InfectiousCheckBox.Value = not(app.InfectiousCheckBox.Value);
                app.updateAxis();
            elseif key == 's'
                app.SucceptableCheckBox.Value = not(app.SucceptableCheckBox.Value);
                app.updateAxis();
            end
        end

        % Menu selected function: AddGroupMenu
        function AddGroupMenuSelected(app, event)
            app.addGroup()
        end

        % Cell edit callback: GroupTable
        function GroupTableCellEdit(app, event)
            indices = event.Indices;
            row = indices(2);
            % newData = event.NewData;
            if(row == 1)
                app.updateAxis();
            elseif(indices(2) == 2)
                app.updateGroups();
            else
                app.updateModel();
            end
        end

        % Value changed function: StepsSpinner
        function StepsSpinnerValueChanged(app, event)
            app.resizeModel();
        end

        % Value changed function: StepSizeField
        function StepSizeFieldValueChanged(app, event)
            app.updateModel();
        end

        % Cell edit callback: BetaTable
        function BetaTableCellEdit(app, event)
            app.updateModel();
            app.updateHeatMap();
        end

        % Value changed function: SucceptableCheckBox
        function SucceptableCheckBoxValueChanged(app, event)
            % value = app.SucceptableCheckBox.Value;
            app.updateAxis();
        end

        % Value changed function: InfectiousCheckBox
        function InfectiousCheckBoxValueChanged(app, event)
            % value = app.InfectiousCheckBox.Value;
            app.updateAxis();
        end

        % Value changed function: RemovedCheckBox
        function RemovedCheckBoxValueChanged(app, event)
            % value = app.RemovedCheckBox.Value;
            app.updateAxis();
        end

        % Context menu opening function: GroupTableContextMeny
        function GroupTableContextMenyContextMenuOpening(app, event)
            
        end

        % Cell selection callback: GroupTable
        function GroupTableCellSelection(app, event)
            app.GroupTableSelection = event.Indices;
            s = length(app.selectedGroups);
            if s == 1
                app.RemoveGroupsMenu.Text = 'Remove Group';
                app.RemoveGroupsMenu.Enable = true;
            elseif s > 1
                app.RemoveGroupsMenu.Text = ['Remove ' num2str(s) ' Groups'];
                app.RemoveGroupsMenu.Enable = true;
            else
                app.RemoveGroupsMenu.Text = 'Remove Group(s)';
                app.RemoveGroupsMenu.Enable = false;
            end
            
            
        end

        % Menu selected function: RemoveGroupsMenu
        function RemoveGroupsMenuSelected(app, event)
            app.removeSelectedGroups()
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            app.updateAxis();
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            app.updateAxis();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.VerticalGridLayout = uigridlayout(app.UIFigure);
            app.HorizontalGridLayout = uigridlayout(app.VerticalGridLayout);
            app.ModelOptionsPanel = uipanel(app.HorizontalGridLayout);
            app.ModelOptionsPanel.Title = 'Model Options';
            app.StepsSpinnerLabel = uilabel(app.ModelOptionsPanel);
            app.StepsSpinnerLabel.Text = 'Steps:';
            app.StepsSpinner = uispinner(app.ModelOptionsPanel);
            app.StepsSpinner.Step = 10;
            app.StepsSpinner.Limits = [10 Inf];
            app.StepsSpinner.ValueChangedFcn = createCallbackFcn(app, @StepsSpinnerValueChanged, true);
            app.StepsSpinner.Value = 1000;
            app.StepsizeLabel = uilabel(app.ModelOptionsPanel);
            app.StepsizeLabel.Text = 'Step size:';
            app.StepSizeField = uieditfield(app.ModelOptionsPanel, 'numeric');
            app.StepSizeField.Limits = [1e-07 Inf];
            app.StepSizeField.ValueChangedFcn = createCallbackFcn(app, @StepSizeFieldValueChanged, true);
            app.StepSizeField.Value = 1;
            app.SucceptableCheckBox = uicheckbox(app.ModelOptionsPanel);
            app.SucceptableCheckBox.ValueChangedFcn = createCallbackFcn(app, @SucceptableCheckBoxValueChanged, true);
            app.SucceptableCheckBox.Text = '[S]ucceptable';
            app.SucceptableCheckBox.Value = true;
            app.ShowLabel = uilabel(app.ModelOptionsPanel);
            app.ShowLabel.Text = 'Show:';
            app.InfectiousCheckBox = uicheckbox(app.ModelOptionsPanel);
            app.InfectiousCheckBox.ValueChangedFcn = createCallbackFcn(app, @InfectiousCheckBoxValueChanged, true);
            app.InfectiousCheckBox.Text = '[I]nfectious';
            app.InfectiousCheckBox.Value = true;
            app.RemovedCheckBox = uicheckbox(app.ModelOptionsPanel);
            app.RemovedCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemovedCheckBoxValueChanged, true);
            app.RemovedCheckBox.Text = '[R]emoved';
            app.RemovedCheckBox.Value = true;
            app.YAxisDropDownLabel = uilabel(app.ModelOptionsPanel);
            app.YAxisDropDownLabel.Text = 'Y-Axis:';
            app.YAxisDropDown = uidropdown(app.ModelOptionsPanel);
            app.YAxisDropDown.Items = {'Number of people', 'Percentages', 'Total Stack', 'Ratio Stack'};
            app.YAxisDropDown.ItemsData = [1 2 3 4];
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 1;
            app.XAxisDropDownLabel = uilabel(app.ModelOptionsPanel);
            app.XAxisDropDownLabel.Text = 'X-Axis:';
            app.XAxisDropDown = uidropdown(app.ModelOptionsPanel);
            app.XAxisDropDown.Items = {'Step Number', 'Time in hours', 'Time in days', 'Time in weeks'};
            app.XAxisDropDown.ItemsData = [1 2 3 4];
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 3;
            app.Plots = uitabgroup(app.HorizontalGridLayout);
            app.TimePlotTab = uitab(app.Plots);
            app.TimePlotTab.Title = 'Time Plot';
            app.TimeAxes = uiaxes(app.TimePlotTab);
            xlabel(app.TimeAxes, 'time in days')
            ylabel(app.TimeAxes, 'number of people')
            zlabel(app.TimeAxes, 'Z')
            app.TimeTable = uitab(app.Plots);
            app.TimeTable.Title = 'Time Table';
            app.DataTable = uitable(app.TimeTable);
            app.DataTable.ColumnName = '';
            app.DataTable.RowName = {};
            app.TransmissionRatesTab_2 = uitab(app.Plots);
            app.TransmissionRatesTab_2.Title = 'Transmission Rates';
            app.TransmissionHeatMapAxes = uiaxes(app.TransmissionRatesTab_2);
            xlabel(app.TransmissionHeatMapAxes, 'Carrier')
            ylabel(app.TransmissionHeatMapAxes, 'Receiver')
            app.TabGroup = uitabgroup(app.VerticalGridLayout);
            app.GroupsandInitialValuesTab = uitab(app.TabGroup);
            app.GroupsandInitialValuesTab.Title = 'Groups and Initial Values';
            app.GroupTable = uitable(app.GroupsandInitialValuesTab);
            app.GroupTable.ColumnName = {'Show'; 'Name'; 'S(0)'; 'I(0)'; 'R(0)'; 'Recovery Rate'};
            app.GroupTable.RowName = {};
            app.GroupTable.ColumnEditable = true;
            app.GroupTable.CellEditCallback = createCallbackFcn(app, @GroupTableCellEdit, true);
            app.GroupTable.CellSelectionCallback = createCallbackFcn(app, @GroupTableCellSelection, true);
            app.TransmissionRatesTab = uitab(app.TabGroup);
            app.TransmissionRatesTab.Title = 'Transmission Rates (beta)';
            app.BetaTable = uitable(app.TransmissionRatesTab);
            app.BetaTable.ColumnName = '';
            app.BetaTable.RowName = {};
            app.BetaTable.ColumnEditable = true;
            app.BetaTable.CellEditCallback = createCallbackFcn(app, @BetaTableCellEdit, true);
            app.GroupTableContextMeny = uicontextmenu(app.UIFigure);
            app.GroupTableContextMeny.ContextMenuOpeningFcn = createCallbackFcn(app, @GroupTableContextMenyContextMenuOpening, true);
            % Assign app.GroupTableContextMeny
            app.GroupTable.ContextMenu = app.GroupTableContextMeny;
            app.AddGroupMenu = uimenu(app.GroupTableContextMeny);
            app.AddGroupMenu.MenuSelectedFcn = createCallbackFcn(app, @AddGroupMenuSelected, true);
            app.AddGroupMenu.Text = 'Add Group';
            app.RemoveGroupsMenu = uimenu(app.GroupTableContextMeny);
            app.RemoveGroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveGroupsMenuSelected, true);
            app.RemoveGroupsMenu.Enable = 'off';
            app.RemoveGroupsMenu.Text = 'Remove Group(s)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIR_model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end